classdef DetectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Group13GlovesDefectDetectionSystemLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        LatexGloveTab                  matlab.ui.container.Tab
        BeginImageDetectionButton      matlab.ui.control.Button
        ImageFilenameEditField         matlab.ui.control.EditField
        ImageFilenameEditFieldLabel    matlab.ui.control.Label
        ImportImageButton              matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        CottonGloveTab                 matlab.ui.container.Tab
        BeginImageDetectionButton_2    matlab.ui.control.Button
        ImageFilenameEditField_2       matlab.ui.control.EditField
        ImageFilenameEditField_2Label  matlab.ui.control.Label
        ImportImageButton_2            matlab.ui.control.Button
        UIAxes_3                       matlab.ui.control.UIAxes
        NitrileGloveTab                matlab.ui.container.Tab
        DefectEditField                matlab.ui.control.EditField
        DefectEditFieldLabel           matlab.ui.control.Label
        ResultEditField                matlab.ui.control.EditField
        ResultLabel                    matlab.ui.control.Label
        BeginImageDetectionButton_3    matlab.ui.control.Button
        ImageFilenameEditField_3       matlab.ui.control.EditField
        ImageFilenameEditField_3Label  matlab.ui.control.Label
        ImportImageButton_3            matlab.ui.control.Button
        UIAxes_4                       matlab.ui.control.UIAxes
        PVCNylonGloveTab               matlab.ui.container.Tab
        BeginImageDetectionButton_4    matlab.ui.control.Button
        ImageFilenameEditField_4       matlab.ui.control.EditField
        ImageFilenameEditField_4Label  matlab.ui.control.Label
        ImportImageButton_4            matlab.ui.control.Button
        UIAxes_5                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
    function loadAndDisplayImage(app, ax, fullname)
        img = imread(fullname);              % read image
        ax.UserData = img;                   % store for later use
        imshow(img, 'Parent', ax);           % show on UIAxes
        title(ax, '');
    end

    % ---------------------------------------------------------
        % Core Detection Algorithm adapted for App Designer
        % ---------------------------------------------------------
        function runGloveDetection(app, ax, img)
            [imgHeight, imgWidth, ~] = size(img);
            gray = rgb2gray(img);
            R = double(img(:,:,1));
            G = double(img(:,:,2));
            B = double(img(:,:,3));
            
            % Apply Gaussian smoothing 
            smoothed_gray = imgaussfilt(gray, 2.5);
            
            % --- STEP 1 & 2: Base Mask Extraction ---
            level_loose = graythresh(smoothed_gray);
            bw_loose = imbinarize(smoothed_gray, level_loose * 0.8); 
            
            % Auto-Polarity Reversal (APR)
            corner_pixels = [bw_loose(1:10, 1:10), bw_loose(1:10, end-9:end);
                             bw_loose(end-9:end, 1:10), bw_loose(end-9:end, end-9:end)];
            if sum(corner_pixels(:)) > 0.5 * numel(corner_pixels)
                bw_loose = ~bw_loose; 
            end
            
            bw_loose = imopen(bw_loose, strel('disk', 3)); 
            bw_loose = bwareafilt(bw_loose, 1); 
            bw_loose = imfill(bw_loose, 'holes'); 
            
            level_strict = graythresh(smoothed_gray); 
            bw_bright = imbinarize(smoothed_gray, level_strict * 0.9); 
            
            is_skin = (R > G + 10) & (R > B + 10) & (gray > 40); 
            is_skin = imopen(is_skin, strel('disk', 2)); 
            
            bw_strict = bw_bright & ~is_skin & bw_loose;
            bw_strict = imclose(bw_strict, strel('disk', 3)); 
            bw_strict = imopen(bw_strict, strel('disk', 2));
            
            % --- STEP 3: Missing Finger Detection ---
            all_defects = bw_loose & ~bw_strict; 
            clean_defects = bwareafilt(all_defects, [80, 15000]); 
            
            closed_holes_raw = imfill(bw_strict, 'holes') & ~bw_strict;
            closed_holes_raw = bwareaopen(closed_holes_raw, 15); 
            
            missing_fingers = clean_defects & ~closed_holes_raw & is_skin;
            missing_fingers = bwareaopen(missing_fingers, 250);
            
            % Fuzzy Border Check
            fuzzy_frame_border = false(size(gray));
            fuzzy_frame_border(1:10, :) = true; fuzzy_frame_border(end-9:end, :) = true;
            
            possible_missing_fingers_stats = regionprops(missing_fingers, 'PixelIdxList');
            for m = 1:length(possible_missing_fingers_stats)
                object_idx = possible_missing_fingers_stats(m).PixelIdxList;
                if any(fuzzy_frame_border(object_idx))
                    missing_fingers(object_idx) = false; 
                end
            end
            
            is_dark_bg = double(gray) < 60; 
            genuine_mask = is_dark_bg | is_skin; 
            
            % --- STEP 4: Internal Defect Classification ---
            stats_closed_raw = regionprops(closed_holes_raw, 'BoundingBox', 'Area', 'Eccentricity', 'PixelIdxList', 'MinorAxisLength');
            valid_holes = [];
            valid_threads = []; 
            
            % Color-Agnostic Cuff Detection
            max_c = max(max(R, G), B);
            min_c = min(min(R, G), B);
            color_diff = max_c - min_c;
            is_cuff = (color_diff > 15) & ~is_skin & bw_loose; 
            is_cuff = bwareaopen(is_cuff, 50);
            
            hole_mask = false(size(gray)); 
            
            for k = 1:length(stats_closed_raw)
                area = stats_closed_raw(k).Area;
                idx = stats_closed_raw(k).PixelIdxList;
                
                if sum(is_cuff(idx)) / area > 0.3, continue; end
                genuine_ratio = sum(genuine_mask(idx)) / area;
                
                if area > 80 && genuine_ratio > 0.15
                    valid_holes = [valid_holes; stats_closed_raw(k)];
                    hole_mask(idx) = true; 
                elseif area >= 15 && area <= 1000
                    if stats_closed_raw(k).Eccentricity > 0.70 || genuine_ratio <= 0.15
                        valid_threads = [valid_threads; stats_closed_raw(k)];
                    end
                end
            end
            
            % Exclusion Zone Mask
            border_mask = false(size(gray));
            border_mask(1:20, :) = true; border_mask(end-19:end, :) = true;
            border_mask(:, 1:20) = true; border_mask(:, end-19:end) = true;
            exclusion_zone = imdilate(missing_fingers | hole_mask | is_cuff, strel('disk', 25)) | border_mask;
            
            % --- STEP 5: Edge Loose Thread Detection ---
            bw_solid = imopen(bw_loose, strel('disk', 6)); 
            edge_raw_threads = bw_loose & ~bw_solid;
            
            edge_raw_threads = bwareaopen(edge_raw_threads, 10); 
            edge_raw_threads = edge_raw_threads & ~exclusion_zone;
            
            stats_edge = regionprops(edge_raw_threads, 'BoundingBox', 'Area', 'Eccentricity', 'PixelIdxList', 'MinorAxisLength');
            
            for k = 1:length(stats_edge)
                idx = stats_edge(k).PixelIdxList;
                mean_intensity = mean(double(gray(idx))); 
                area = stats_edge(k).Area;
                ecc = stats_edge(k).Eccentricity;
                minor_axis = stats_edge(k).MinorAxisLength;
                
                is_thin_straight = (area > 20) && (ecc > 0.80) && (minor_axis < 10) && (mean_intensity > graythresh(smoothed_gray) * 255 * 0.60);
                is_curly_bright = (area > 40) && (minor_axis < 15) && (mean_intensity > graythresh(smoothed_gray) * 255 * 0.75);
                
                if is_thin_straight || is_curly_bright
                    valid_threads = [valid_threads; stats_edge(k)]; 
                end
            end
            
            % =========================================================================
            % Final Rendering inside App UIAxes
            % =========================================================================
            imshow(img, 'Parent', ax); % Show image directly on the specific UI axis
            hold(ax, 'on');            % Lock the UI axis for drawing
            
            defect_found = false;
            detected_defects = {};     % Array to store the names of found defects
            
            pad = 10; 
            fs_large = 10; fs_mid = 9; fs_small = 8;
            
            % 1. Draw Missing Finger
            stats_missing = regionprops(missing_fingers, 'BoundingBox');
            if ~isempty(stats_missing)
                detected_defects{end+1} = 'Missing Finger'; % Record defect type
            end
            for k = 1:length(stats_missing)
                bbox = app.add_padding(stats_missing(k).BoundingBox, pad, imgWidth, imgHeight);
                rectangle(ax, 'Position', bbox, 'EdgeColor', 'm', 'LineWidth', 2.5);
                text(ax, bbox(1), bbox(2)-8, 'Missing Finger', 'Color', 'm', 'FontWeight', 'bold', 'FontSize', fs_large);
                defect_found = true;
            end
            
            % 2. Draw Hole
            if ~isempty(valid_holes)
                detected_defects{end+1} = 'Hole'; % Record defect type
            end
            for k = 1:length(valid_holes)
                bbox = app.add_padding(valid_holes(k).BoundingBox, pad, imgWidth, imgHeight);
                rectangle(ax, 'Position', bbox, 'EdgeColor', 'r', 'LineWidth', 2);
                text(ax, bbox(1), bbox(2)-8, 'Hole', 'Color', 'r', 'FontWeight', 'bold', 'FontSize', fs_mid);
                defect_found = true;
            end
            
            % 3. Draw Loose Thread
            if ~isempty(valid_threads)
                detected_defects{end+1} = 'Loose Thread'; % Record defect type
            end
            for k = 1:length(valid_threads)
                bbox = app.add_padding(valid_threads(k).BoundingBox, pad, imgWidth, imgHeight);
                rectangle(ax, 'Position', bbox, 'EdgeColor', 'y', 'LineWidth', 1.5);
                text(ax, bbox(1), bbox(2)-8, 'Loose Thread', 'Color', 'y', 'FontWeight', 'bold', 'FontSize', fs_small);
                defect_found = true;
            end
            
            % Display Result as Title on the UIAxes
            if ~defect_found
                title(ax, 'Result: PASS', 'Color', 'g', 'FontSize', 14, 'FontWeight', 'bold');
            else
                % Combine all detected defects into a single string (e.g., "Hole, Loose Thread")
                defect_str = strjoin(detected_defects, ', ');
                title(ax, ['Result: ', defect_str], 'Color', 'r', 'FontSize', 14, 'FontWeight', 'bold');
            end
            hold(ax, 'off');
        end
        
        % Helper function to apply padding
        function new_bbox = add_padding(app, bbox, pad, imgW, imgH)
            x = max(1, bbox(1) - pad);
            y = max(1, bbox(2) - pad);
            w = min(imgW - x, bbox(3) + 2*pad);
            h = min(imgH - y, bbox(4) + 2*pad);
            new_bbox = [x, y, w, h];
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                % Read axes dimensions once at startup
        axesPos = app.UIAxes_2.Position;
        axesWidth  = axesPos(3);
        axesHeight = axesPos(4);

        % Display the values in the command window so you can see them
        fprintf('Axes Widthmine:  %d px\n', axesWidth);
        fprintf('Axes Heightmine: %d px\n', axesHeight);
        end

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
    %             % Opens a file picker dialog filtered to common image formats.
    % % uigetfile returns the filename and the folder path separately.
    % [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tiff', ...
    %                            'Image Files (*.jpg,*.png,*.bmp,*.tiff)'}, ...
    %                            'Select a Glove Image');
    % 
    % % If the user pressed Cancel, file comes back as 0 — we check for that.
    % if isequal(file, 0)
    %     return;
    % end
    % 
    % % Put ONLY the filename into the edit field, as the user sees it
    % app.ImageFilenameEditField.Value = file;
    % 
    % % Combine folder path and filename into a full path string.
    % filename = fullfile(path, file);
    % app.loadAndDisplayImage(app.UIAxes_2, filename);


    [filename, path] = uigetfile({'*.jpeg;*.jpg;*.png;*.bmp;*.tiff', ...
                                   'Image Files'}, 'Select Glove Image');
    if isequal(filename, 0)
        return;
    end


    % Build full path
    fullname = fullfile(path, filename);

    % Derive clean name (no path, no extension) and update the text field here
    [~, fName, ~] = fileparts(fullname);
    app.ImageFilenameEditField.Value = fName;

    % Now just load and display the image
    app.loadAndDisplayImage(app.UIAxes_2, fullname);
        end

        % Button pushed function: BeginImageDetectionButton
        function BeginImageDetectionButtonPushed(app, event)
                img = app.UIAxes_2.UserData;

    % Check if UserData is empty — means no image has been imported yet
    if isempty(img)
        uialert(app.UIFigure, 'Please import an image first.', 'No Image');
        return;
    end
        end

        % Button pushed function: ImportImageButton_2
        function ImportImageButton_2Pushed(app, event)
                [filename, path] = uigetfile({'*.jpeg;*.jpg;*.png;*.bmp;*.tiff', ...
                                   'Image Files'}, 'Select Glove Image');
    if isequal(filename, 0)
        return;
    end


    % Build full path
    fullname = fullfile(path, filename);

    % Derive clean name (no path, no extension) and update the text field here
    [~, fName, ~] = fileparts(fullname);
    app.ImageFilenameEditField_2.Value = fName;

    % Now just load and display the image
    app.loadAndDisplayImage(app.UIAxes_3, fullname);
        end

        % Button pushed function: BeginImageDetectionButton_2
        function BeginImageDetectionButton_2Pushed(app, event)
                            img = app.UIAxes_3.UserData;

    % Check if UserData is empty — means no image has been imported yet
    if isempty(img)
        uialert(app.UIFigure, 'Please import an image first.', 'No Image');
        return;
    end

        app.runGloveDetection(app.UIAxes_3, img);
        end

        % Button pushed function: BeginImageDetectionButton_3
        function BeginImageDetectionButton_3Pushed(app, event)
            img = app.UIAxes_4.UserData;

            % Check if UserData is empty — means no image has been imported yet
            if isempty(img)
                uialert(app.UIFigure, 'Please import an image first.', 'No Image');
                return;
            end

            app. ResultEditField.Value = 'Loading...';
            app.ResultEditField.FontColor = [1 1 1]; % White
            drawnow; % Force UI to refresh immediately

            % Call defect detection function and display result
            [result, defect] = detectNitrileGloveDefect(img, app.UIAxes_4);

            % Update UI
            if strcmp(result, 'DEFECTIVE')
                app.ResultEditField.FontColor = [1 0 0];   % Red for defective
            elseif strcmp(result, 'NON-DEFECTIVE')
                app.ResultEditField.FontColor = [0 1 0]; % Green for non-defective
            end
            app. ResultEditField.Value = result;
            app. DefectEditField.Value = defect;
        end

        % Button pushed function: BeginImageDetectionButton_4
        function BeginImageDetectionButton_4Pushed(app, event)
                            img = app.UIAxes_5.UserData;

    % Check if UserData is empty — means no image has been imported yet
    if isempty(img)
        uialert(app.UIFigure, 'Please import an image first.', 'No Image');
        return;
    end
        end

        % Button pushed function: ImportImageButton_3
        function ImportImageButton_3Pushed(app, event)
                            [filename, path] = uigetfile({'*.jpeg;*.jpg;*.png;*.bmp;*.tiff', ...
                                   'Image Files'}, 'Select Glove Image');
                            app.ResultEditField.Value = '';
                            app.DefectEditField.Value = '';
                            drawnow;

    if isequal(filename, 0)
        return;
    end


    % Build full path
    fullname = fullfile(path, filename);

    % Derive clean name (no path, no extension) and update the text field here
    [~, fName, ~] = fileparts(fullname);
    app.ImageFilenameEditField_3.Value = fName;

    % Now just load and display the image
    app.loadAndDisplayImage(app.UIAxes_4, fullname);
        end

        % Button pushed function: ImportImageButton_4
        function ImportImageButton_4Pushed(app, event)
                            [filename, path] = uigetfile({'*.jpeg;*.jpg;*.png;*.bmp;*.tiff', ...
                                   'Image Files'}, 'Select Glove Image');
    if isequal(filename, 0)
        return;
    end


    % Build full path
    fullname = fullfile(path, filename);

    % Derive clean name (no path, no extension) and update the text field here
    [~, fName, ~] = fileparts(fullname);
    app.ImageFilenameEditField_4.Value = fName;

    % Now just load and display the image
    app.loadAndDisplayImage(app.UIAxes_5, fullname);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LatexGloveTab = uitab(app.TabGroup);
            app.LatexGloveTab.Title = 'Latex Glove';
            app.UIAxes_2 = uiaxes(app.LatexGloveTab);
            app.ImportImageButton = uibutton(app.LatexGloveTab, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Tooltip = {'Click this to import an Image from your local storage'};
            app.ImportImageButton.Text = 'Import Image';
            app.ImageFilenameEditFieldLabel = uilabel(app.LatexGloveTab);
            app.ImageFilenameEditFieldLabel.Text = 'Image Filename:';
            app.ImageFilenameEditField = uieditfield(app.LatexGloveTab, 'text');
            app.ImageFilenameEditField.Editable = 'off';
            app.ImageFilenameEditField.Tooltip = {'The Filename of your image will be shown here'};
            app.BeginImageDetectionButton = uibutton(app.LatexGloveTab, 'push');
            app.BeginImageDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @BeginImageDetectionButtonPushed, true);
            app.BeginImageDetectionButton.Tooltip = {'Click this to start detecting defects on your glove image'};
            app.BeginImageDetectionButton.Text = 'Begin Image Detection';
            app.CottonGloveTab = uitab(app.TabGroup);
            app.CottonGloveTab.Title = 'Cotton Glove';
            app.UIAxes_3 = uiaxes(app.CottonGloveTab);
            app.ImportImageButton_2 = uibutton(app.CottonGloveTab, 'push');
            app.ImportImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButton_2Pushed, true);
            app.ImportImageButton_2.Tooltip = {'Click this to import an Image from your local storage'};
            app.ImportImageButton_2.Text = 'Import Image';
            app.ImageFilenameEditField_2Label = uilabel(app.CottonGloveTab);
            app.ImageFilenameEditField_2Label.Text = 'Image Filename:';
            app.ImageFilenameEditField_2 = uieditfield(app.CottonGloveTab, 'text');
            app.ImageFilenameEditField_2.Editable = 'off';
            app.ImageFilenameEditField_2.Tooltip = {'The Filename of your image will be shown here'};
            app.BeginImageDetectionButton_2 = uibutton(app.CottonGloveTab, 'push');
            app.BeginImageDetectionButton_2.ButtonPushedFcn = createCallbackFcn(app, @BeginImageDetectionButton_2Pushed, true);
            app.BeginImageDetectionButton_2.Tooltip = {'Click this to start detecting defects on your glove image'};
            app.BeginImageDetectionButton_2.Text = 'Begin Image Detection';
            app.NitrileGloveTab = uitab(app.TabGroup);
            app.NitrileGloveTab.Title = 'Nitrile Glove';
            app.UIAxes_4 = uiaxes(app.NitrileGloveTab);
            app.ImportImageButton_3 = uibutton(app.NitrileGloveTab, 'push');
            app.ImportImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButton_3Pushed, true);
            app.ImportImageButton_3.Tooltip = {'Click this to import an Image from your local storage'};
            app.ImportImageButton_3.Text = 'Import Image';
            app.ImageFilenameEditField_3Label = uilabel(app.NitrileGloveTab);
            app.ImageFilenameEditField_3Label.Text = 'Image Filename:';
            app.ImageFilenameEditField_3 = uieditfield(app.NitrileGloveTab, 'text');
            app.ImageFilenameEditField_3.Editable = 'off';
            app.ImageFilenameEditField_3.Tooltip = {'The Filename of your image will be shown here'};
            app.BeginImageDetectionButton_3 = uibutton(app.NitrileGloveTab, 'push');
            app.BeginImageDetectionButton_3.ButtonPushedFcn = createCallbackFcn(app, @BeginImageDetectionButton_3Pushed, true);
            app.BeginImageDetectionButton_3.Tooltip = {'Click this to start detecting defects on your glove image'};
            app.BeginImageDetectionButton_3.Text = 'Begin Image Detection';
            app.ResultLabel = uilabel(app.NitrileGloveTab);
            app.ResultLabel.Text = 'Result:';
            app.ResultEditField = uieditfield(app.NitrileGloveTab, 'text');
            app.DefectEditFieldLabel = uilabel(app.NitrileGloveTab);
            app.DefectEditFieldLabel.Text = 'Defect:';
            app.DefectEditField = uieditfield(app.NitrileGloveTab, 'text');
            app.PVCNylonGloveTab = uitab(app.TabGroup);
            app.PVCNylonGloveTab.Title = 'PVC/Nylon Glove';
            app.UIAxes_5 = uiaxes(app.PVCNylonGloveTab);
            app.ImportImageButton_4 = uibutton(app.PVCNylonGloveTab, 'push');
            app.ImportImageButton_4.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButton_4Pushed, true);
            app.ImportImageButton_4.Tooltip = {'Click this to import an Image from your local storage'};
            app.ImportImageButton_4.Text = 'Import Image';
            app.ImageFilenameEditField_4Label = uilabel(app.PVCNylonGloveTab);
            app.ImageFilenameEditField_4Label.Text = 'Image Filename:';
            app.ImageFilenameEditField_4 = uieditfield(app.PVCNylonGloveTab, 'text');
            app.ImageFilenameEditField_4.Editable = 'off';
            app.ImageFilenameEditField_4.Tooltip = {'The Filename of your image will be shown here'};
            app.BeginImageDetectionButton_4 = uibutton(app.PVCNylonGloveTab, 'push');
            app.BeginImageDetectionButton_4.ButtonPushedFcn = createCallbackFcn(app, @BeginImageDetectionButton_4Pushed, true);
            app.BeginImageDetectionButton_4.Tooltip = {'Click this to start detecting defects on your glove image'};
            app.BeginImageDetectionButton_4.Text = 'Begin Image Detection';
            app.Group13GlovesDefectDetectionSystemLabel = uilabel(app.UIFigure);
            app.Group13GlovesDefectDetectionSystemLabel.Text = 'Group 13 Gloves Defect Detection System';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DetectionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end